classdef explore_factors < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        DropDown                  matlab.ui.control.DropDown
        DropDownLabel             matlab.ui.control.Label
        LoadTrialButton           matlab.ui.control.Button
        LoaddataatTimpointButton  matlab.ui.control.Button
        BranchDropDown            matlab.ui.control.DropDown
        BranchDropDownLabel       matlab.ui.control.Label
        TreeDisplayButtonGroup    matlab.ui.container.ButtonGroup
        ClustersButton            matlab.ui.control.ToggleButton
        FactorsButton             matlab.ui.control.ToggleButton
        BranchesButton            matlab.ui.control.ToggleButton
        Loadings_fig              matlab.ui.control.UIAxes
        Traces_fig                matlab.ui.control.UIAxes
        Tree_fig                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        expe % Description
        highlight_trace % Description
    end
    
    methods (Access = private)
        
        function embed_tree(app, fig_num)
            if nargin < 2 || isempty(fig_num)
                fig     = gcf;
            elseif ~ishandle(fig_num)
                fig     = figure(fig_num);
            else
                fig     = fig_num;
            end
            
            cla(app.Tree_fig)
            ax      = findobj(fig,'Type','Axes');  
            copyobj(ax.Children, app.Tree_fig); 
            rotate3d(app.Tree_fig,'on');axis(app.Tree_fig, 'off')
            adjust_tree_plot('3D view',app.Tree_fig);
            caxis(app.Tree_fig,[0,0.2]);
            close(fig);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, expe)
            app.expe = expe;
        end

        % Selection changed function: TreeDisplayButtonGroup
        function TreeDisplayButtonGroupSelectionChanged(app, event)
            mode = app.TreeDisplayButtonGroup.SelectedObject;
            app.BranchDropDown.Value = 'All'; % reset selection
            if strcmpi(mode.Text, 'branches')
                app.BranchDropDownLabel.Text = 'branch #';
                app.BranchDropDown.Items = splitlines(sprintf('\n%i',1:numel(app.expe.ref.indices.br_start_idx)));
                app.BranchDropDown.Items{1} = 'All';
            elseif strcmpi(mode.Text, 'factors') 
                app.BranchDropDownLabel.Text = 'factor #';
                app.BranchDropDown.Items = splitlines(sprintf('\n%i',1:app.expe.dimensionality.n_factors));
                app.BranchDropDown.Items{1} = 'All'; 
                app.expe.plot_factor_tree('','','', app.Loadings_fig, app.Traces_fig);
                app.embed_tree(10200);
            elseif strcmpi(mode.Text, 'clusters') 
                app.BranchDropDownLabel.Text = 'cluster #';
                app.BranchDropDown.Items = splitlines(sprintf('\n%i',1:nanmax(app.expe.dimensionality.cluster_idx)));
                app.BranchDropDown.Items{1} = 'All';   
                app.expe.plot_cluster_tree('', app.Loadings_fig, app.Traces_fig); 
                app.embed_tree(gcf);
            end

            
        end

        % Value changed function: BranchDropDown
        function BranchDropDownValueChanged(app, event)
            value = str2num(app.BranchDropDown.Value);
            if strcmpi(app.TreeDisplayButtonGroup.SelectedObject.Text, 'factors') 
                if ~isempty(value)
                    app.expe.plot_dim_tree(value);
                    app.embed_tree(gcf);
                    rescaled_traces = app.expe.rescaled_traces(:, app.expe.dimensionality.all_ROIs(app.expe.dimensionality.valid_trace_idx));
                    value          = app.expe.dimensionality.LoadingsPM(:,value)/sum(app.expe.dimensionality.LoadingsPM(:,value));       
                    delete(app.highlight_trace);
                    app.highlight_trace = plot(app.Traces_fig, app.expe.t, nanmean(rescaled_traces'.* value, 1),'Color','r','LineWidth',2);
                else
                    app.expe.plot_factor_tree('','','', app.Loadings_fig, app.Traces_fig);
                    app.embed_tree(gcf);
                end
            elseif strcmpi(app.TreeDisplayButtonGroup.SelectedObject.Text, 'clusters') 
                if ~isempty(value)
                    cluster_idx = app.expe.dimensionality.cluster_idx;
                    cluster_idx(cluster_idx ~= value) = 0;
                    app.expe.ref.plot_value_tree(cluster_idx, find(app.expe.dimensionality.valid_trace_idx), app.expe.default_handle, 'Clusters','',10200, 'regular', 'viridis');      
                    app.embed_tree(gcf);
                    rescaled_traces = app.expe.rescaled_traces(:, app.expe.dimensionality.valid_trace_idx);
                    value           = nanmean(rescaled_traces(:, cluster_idx>0),2);
                    delete(app.highlight_trace);
                    app.highlight_trace = plot(app.Traces_fig, app.expe.t, value,'Color','r','LineWidth',2);
                else
                    app.expe.plot_cluster_tree('',app.Loadings_fig, app.Traces_fig);
                    app.embed_tree(gcf);
                end
            end
        end

        % Button pushed function: LoaddataatTimpointButton
        function LoaddataatTimpointButtonPushed(app, event)
            app.UIFigure.HandleVisibility = 'callback';
            pt = ginput(1);
            app.UIFigure.HandleVisibility = 'off';
            pt = pt(1);t_cumsum = cumsum(app.expe.timescale.durations);
            record = find(t_cumsum < pt, 1, 'last');
            t_in_record = pt - t_cumsum(record);
            pt_in_record = t_in_record / app.expe.timescale.sr(record);
            source = app.expe.arboreal_scans{record}.data_folder;
            ap = app.expe.arboreal_scans{record}.analysis_params;
            p = analysis_params('data_folder',ap.data_folder,...
                                'data_type'      , 'raw',...
                                    'rendering_mode', 'mosaic',...
                                   'tracing_source' , 'swc'         , ...
                                   'signal_channel' , 2             ,...
                                   'registration_channel', 2,.... % to fix
                                   'registration'   , true          ,...
                                   'registration_reload','auto_offsets.mat',...
                                   'smoothing'      , [10,0]        ,...
                                   'mask_method'    , ''   ,...
                                   'mask_value'     , ''     ,...
                                   'compression'    , '2D'          ,...
                                   'rendering'      , false         ,...
                                   'z_score'        ,-5); 
            data = load_ribbon_scan(p);            
            max_projection(data{1}(:,:,:,max(t_in_record-10, 1):min(t_in_record+10, end),:));
        end

        % Button pushed function: LoadTrialButton
        function LoadTrialButtonPushed(app, event)
            app.UIFigure.HandleVisibility = 'callback';
            pt = ginput(1);
            app.UIFigure.HandleVisibility = 'off';
            pt = pt(1);t_cumsum = cumsum(app.expe.timescale.durations);
            record = find(t_cumsum < pt, 1, 'last');
            t_in_record = pt - t_cumsum(record);
            pt_in_record = t_in_record / app.expe.timescale.sr(record);
            source = app.expe.arboreal_scans{record}.data_folder;
            ap = app.expe.arboreal_scans{record}.analysis_params;
            p = analysis_params('data_folder',ap.data_folder,...
                                'data_type'      , 'concatenated',...
                                    'rendering_mode', 'mosaic',...
                                   'tracing_source' , 'swc'         , ...
                                   'signal_channel' , 2             ,...
                                   'registration_channel', 2,.... % to fix
                                   'registration'   , true          ,...
                                   'registration_reload','auto_offsets.mat',...
                                   'smoothing'      , [10,0]        ,...
                                   'mask_method'    , ''   ,...
                                   'mask_value'     , ''     ,...
                                   'compression'    , '2D'          ,...
                                   'rendering'      , true         ,...
                                   'ROIs',find(app.expe.dimensionality.valid_trace_idx),...
                                   'z_score'        ,-5); 
            data = load_ribbon_scan(p);         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Tree_fig = uiaxes(app.UIFigure);
            title(app.Tree_fig, 'Title')
            xlabel(app.Tree_fig, 'X')
            ylabel(app.Tree_fig, 'Y')
            zlabel(app.Tree_fig, 'Z')
            app.Traces_fig = uiaxes(app.UIFigure);
            title(app.Traces_fig, 'Title')
            xlabel(app.Traces_fig, 'X')
            ylabel(app.Traces_fig, 'Y')
            zlabel(app.Traces_fig, 'Z')
            app.Loadings_fig = uiaxes(app.UIFigure);
            title(app.Loadings_fig, 'Title')
            xlabel(app.Loadings_fig, 'X')
            ylabel(app.Loadings_fig, 'Y')
            zlabel(app.Loadings_fig, 'Z')
            app.TreeDisplayButtonGroup = uibuttongroup(app.UIFigure);
            app.TreeDisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TreeDisplayButtonGroupSelectionChanged, true);
            app.TreeDisplayButtonGroup.Title = 'Tree Display';
            app.BranchesButton = uitogglebutton(app.TreeDisplayButtonGroup);
            app.BranchesButton.Text = 'Branches';
            app.BranchesButton.Value = true;
            app.FactorsButton = uitogglebutton(app.TreeDisplayButtonGroup);
            app.FactorsButton.Text = 'Factors';
            app.ClustersButton = uitogglebutton(app.TreeDisplayButtonGroup);
            app.ClustersButton.Text = 'Clusters';
            app.BranchDropDownLabel = uilabel(app.UIFigure);
            app.BranchDropDownLabel.Text = 'Branch';
            app.BranchDropDown = uidropdown(app.UIFigure);
            app.BranchDropDown.Items = {'All'};
            app.BranchDropDown.ValueChangedFcn = createCallbackFcn(app, @BranchDropDownValueChanged, true);
            app.BranchDropDown.Value = 'All';
            app.LoaddataatTimpointButton = uibutton(app.UIFigure, 'push');
            app.LoaddataatTimpointButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataatTimpointButtonPushed, true);
            app.LoaddataatTimpointButton.Text = 'Load data at Timpoint';
            app.LoadTrialButton = uibutton(app.UIFigure, 'push');
            app.LoadTrialButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrialButtonPushed, true);
            app.LoadTrialButton.Text = 'Load Trial';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Mosaic', 'realistic', 'Option 3', 'Option 4'};
            app.DropDown.Value = 'Mosaic';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = explore_factors(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end